classdef gui3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FieldPic                 matlab.ui.control.TextArea
        BrowsePic                matlab.ui.control.Button
        HaningNandaHapsariLabel  matlab.ui.control.Label
        OperatorChoosen          matlab.ui.control.DropDown
        OperatorDropDownLabel    matlab.ui.control.Label
        Th                       matlab.ui.control.Slider
        ThresholdSliderLabel     matlab.ui.control.Label
        ImageSegmentationLabel   matlab.ui.control.Label
        SegmentationImage        matlab.ui.control.UIAxes
        EdgeImage                matlab.ui.control.UIAxes
        RealImage                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageFile % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowsePic
        function BrowsePicButtonPushed(app, event)
            % read image file
            [filename,filepath] = uigetfile({'*.jpg;*.tif;*.png;*.gif;*.bmp'}, 'Select File to Open');
            if ischar(filepath)
                fullname = [filepath, filename];
                app.ImageFile= imread(fullname);
    
                % change label & input image
                app.FieldPic.Value = filename;
                imshow(app.ImageFile, 'Parent', app.RealImage);
                type = app.OperatorChoosen.Value;
                th = get(app.Th, 'Value');
                if size(app.ImageFile,3)==3
                    img= rgb2gray(app.ImageFile);
                else
                    img= app.ImageFile;
                end
                if type =="Canny"
                    result = TypeDetection.canny(img);
                elseif type =="Prewitt"
                    result = TypeDetection.prewitt(img);
                elseif type == "Sobel"
                    result = TypeDetection.sobel(img);
                elseif type == "Roberts"
                    result = TypeDetection.roberts(img);
                elseif type == "Laplace"
                    result = TypeDetection.laplace(img);
                elseif type == "LoG"
                    result = TypeDetection.log(img);
                end
                segmentation = TypeDetection.segmentation(app.ImageFile,th,type);
                imshow(result,'Parent',app.EdgeImage)
                imshow(segmentation,'Parent',app.SegmentationImage)
            end
        end

        % Value changed function: OperatorChoosen
        function OperatorChoosenValueChanged(app, event)
            type = app.OperatorChoosen.Value;
                th = get(app.Th, 'Value');
                if size(app.ImageFile,3)==3
                    img= rgb2gray(app.ImageFile);
                else
                    img= app.ImageFile;
                end
                if type =="Canny"
                    result = TypeDetection.canny(img);
                elseif type =="Prewitt"
                    result = TypeDetection.prewitt(img);
                elseif type == "Sobel"
                    result = TypeDetection.sobel(img);
                elseif type == "Roberts"
                    result = TypeDetection.roberts(img);
                elseif type == "Laplace"
                    result = TypeDetection.laplace(img);
                elseif type == "LoG"
                    result = TypeDetection.log(img);
                end
                segmentation = TypeDetection.segmentation(app.ImageFile,th,type);
                imshow(result,'Parent',app.EdgeImage)
                imshow(segmentation,'Parent',app.SegmentationImage)
        end

        % Value changed function: Th
        function ThValueChanged(app, event)
            type = app.OperatorChoosen.Value;
            th = get(app.Th, 'Value');
            if size(app.ImageFile,3)==3
                img= rgb2gray(app.ImageFile);
            else
                img= app.ImageFile;
            end
            if type =="Canny"
                result = TypeDetection.canny(img);
            elseif type =="Prewitt"
                result = TypeDetection.prewitt(img);
            elseif type == "Sobel"
                result = TypeDetection.sobel(img);
            elseif type == "Roberts"
                result = TypeDetection.roberts(img);
            elseif type == "Laplace"
                result = TypeDetection.laplace(img);
            elseif type == "LoG"
                result = TypeDetection.log(img);
            end
            segmentation = TypeDetection.segmentation(app.ImageFile,th,type);
            imshow(result,'Parent',app.EdgeImage)
            imshow(segmentation,'Parent',app.SegmentationImage)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RealImage = uiaxes(app.UIFigure);
            title(app.RealImage, 'Real Image')
            app.RealImage.XTick = [];
            app.RealImage.YTick = [];
            app.EdgeImage = uiaxes(app.UIFigure);
            title(app.EdgeImage, 'Edge Image')
            app.EdgeImage.XTick = [];
            app.EdgeImage.YTick = [];
            app.SegmentationImage = uiaxes(app.UIFigure);
            title(app.SegmentationImage, 'Segmentation Image')
            app.SegmentationImage.XTick = [];
            app.SegmentationImage.YTick = [];
            app.ImageSegmentationLabel = uilabel(app.UIFigure);
            app.ImageSegmentationLabel.Text = 'Image Segmentation';
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.Th = uislider(app.UIFigure);
            app.Th.Limits = [0 255];
            app.Th.ValueChangedFcn = createCallbackFcn(app, @ThValueChanged, true);
            app.Th.Value = 20;
            app.OperatorDropDownLabel = uilabel(app.UIFigure);
            app.OperatorDropDownLabel.Text = 'Operator';
            app.OperatorChoosen = uidropdown(app.UIFigure);
            app.OperatorChoosen.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.OperatorChoosen.ValueChangedFcn = createCallbackFcn(app, @OperatorChoosenValueChanged, true);
            app.OperatorChoosen.Value = 'Canny';
            app.HaningNandaHapsariLabel = uilabel(app.UIFigure);
            app.HaningNandaHapsariLabel.Text = '13519042 Haning Nanda Hapsari';
            app.BrowsePic = uibutton(app.UIFigure, 'push');
            app.BrowsePic.ButtonPushedFcn = createCallbackFcn(app, @BrowsePicButtonPushed, true);
            app.BrowsePic.Text = 'Browse';
            app.FieldPic = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end